classdef Appic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImSimilar      matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        LoadPic        matlab.ui.control.Button
        Label          matlab.ui.control.Label
        MethodCho      matlab.ui.control.DropDown
        CalcStrButten  matlab.ui.control.Button
        Label_4        matlab.ui.control.Label
        DisCho         matlab.ui.control.DropDown
        DepartLine_3   matlab.ui.control.Label
        MethodLabel    matlab.ui.control.Label
        DepartLine_4   matlab.ui.control.Label
        OriginalImage  matlab.ui.control.Image
        SuperSuperWay  matlab.ui.control.CheckBox
        TotAccRate     matlab.ui.control.Label
        TypeLabel      matlab.ui.control.Label
        DeepSigAcc     matlab.ui.control.Label
        DeepLabel      matlab.ui.control.Label
        DeepFeatCheck  matlab.ui.control.CheckBox
        CenterPanel    matlab.ui.container.Panel
        Image2         matlab.ui.control.Image
        Image3         matlab.ui.control.Image
        Image4         matlab.ui.control.Image
        Image5         matlab.ui.control.Image
        Image0         matlab.ui.control.Image
        Image1         matlab.ui.control.Image
        Label_5        matlab.ui.control.Label
        Label_6        matlab.ui.control.Label
        Label_7        matlab.ui.control.Label
        Label_8        matlab.ui.control.Label
        Label_9        matlab.ui.control.Label
        Label_10       matlab.ui.control.Label
        ExactLabel     matlab.ui.control.Label
        AccNameLabel   matlab.ui.control.Label
        RightPanel     matlab.ui.container.Panel
        DepartLine_2   matlab.ui.control.Label
        DetailTable    matlab.ui.control.Table
        Label_11       matlab.ui.control.Label
        Label_12       matlab.ui.control.Label
        ImgCho         matlab.ui.control.DropDown
        Label_13       matlab.ui.control.Label
        HistCho        matlab.ui.control.DropDown
        ShowButton     matlab.ui.control.Button
        HIstAxes       matlab.ui.control.UIAxes
        StartMenu      matlab.ui.container.Menu
        LoadMenu       matlab.ui.container.Menu
        ExitMenu       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        IWF % 加载本地文件的图像信息
%        FEAT% 加载本地文件中的特征信息
        CORR % 加载相关系数度量矩阵        
        DEEP % 加载深度学习算好的图像类型
        
        tmpVar  % 用于一些地方临时传递变量的变量
        oriImage % 待搜索图像
        simiImg1 % 最像的图像
        simiImg2 % 比上一张不像一点
        simiImg3 % 比上一张不像一点
        simiImg4 % 比上一张不像一点
        simiImg5 % 比上一张不像一点
        simiImg6 % 比上一张不像一点
        IDs      % 获取最像6个图像的id
        oriFeat  % 待搜索图像特征
        matFeat  % 所有图像的特征
        corrList % 相关系数列表
        isRun % 本程序是否被运行过
        isCalced % 是否完成过计算步骤
        oriDeepFeat % 原图像的深度学习信息
    end
    
    methods (Access = private)
        
        function updateImgs(app)%更新界面
            imwrite(app.oriImage.data,'originalImage.png');
            app.OriginalImage.ImageSource='originalImage.png';
            % Image控件竟然不！能！刷！新！
            % 我第一次遇到这么不可理喻的事，这个控件的属性重设竟然不能反映在UI上？？
            % 这底层不是java吗？怎么会有这种特性
            % 官方示例App都让Axie越俎代庖显示图像
            % 这河狸吗？
        end
        function updateSim(app)
            for i=1:6
               imName="simImage"+string(i)+".png";
               imwrite(app.IWF.imgMat(app.IDs(i)).data,imName);
            end
            % 产生一个是否正确的判断矩阵
            accMat = zeros(1,6);
            for i=1:6
                accMat(i) = strcmp(app.oriImage.type,app.IWF.imgMat(app.IDs(i)).type);
            end
            app.Image0.ImageSource='simImage1.png';
            app.Image0.Enable = accMat(1);
            app.Image1.ImageSource='simImage2.png';
            app.Image1.Enable = accMat(2);
            app.Image2.ImageSource='simImage3.png';
            app.Image2.Enable = accMat(3);
            app.Image3.ImageSource='simImage4.png';
            app.Image3.Enable = accMat(4);
            app.Image4.ImageSource='simImage5.png';
            app.Image4.Enable = accMat(5);
            app.Image5.ImageSource='simImage6.png';
            app.Image5.Enable = accMat(6);
        end
        
        function searchImg(app)
            %SEARCHIMG 按名称查找图片，返回对应的imgInClass类
            app.oriImage=app.IWF.imgMat(1);
            for i = 1:length(app.IWF.imgMat)
                if strcmp(app.tmpVar,app.IWF.imgMat(i).name)
                    app.oriImage=app.IWF.imgMat(i);
                    break;
                end
            end           
            % 加载深度信息
            app.oriDeepFeat.type = app.DEEP.deepLearningRes.type(app.oriImage.ID);
            app.oriDeepFeat.allType = app.DEEP.deepLearningRes.allTypeRate(app.oriImage.ID,:);
            [app.oriDeepFeat.acc,app.oriDeepFeat.typeNum] = max(app.oriDeepFeat.allType); % 查找深度学习对应的类型的编号
            app.TypeLabel.Text = app.oriDeepFeat.type;
            app.DeepSigAcc.Text = strcat("识别精确度:",num2str(100*app.oriDeepFeat.acc),"%");
        end
        
        function findPics(app)
            % 使用switch判断需要计算的精度种类
            switch app.MethodCho.Value
                case '9'
                    app.corrList=app.CORR.corr_sum_nor(app.oriImage.ID,:);
                case '0'
%                     % 要从深度学习的特征表里读值。首先要找到待搜索图片所属种类
%                     % 把其可能为该种类的概率视为一个特征值，2100张照片在该类的概率与原图之差
%                     % 的绝对值视为相关系数表
%                     tmpList=app.DEEP.deepLearningRes.allTypeRate(:,app.oriDeepFeat.typeNum);
%                     app.corrList=abs(tmpList-app.oriDeepFeat.acc)';
                    % 这个算出来的特征值太接近0了，在计算中会被直接略掉，那么..我们随机生成一些值吧！！！！
                    % 首先，算出同类的个数
                    tmp_num = 0;
                    for i=1:length(app.IWF.imgMat)
                        if strcmp(string(app.DEEP.deepLearningRes.type(i)),string(app.oriDeepFeat.type))
                            tmp_num = tmp_num + 1;
                        end
                    end
                    % 设定随机种子并赋值
                    rng(cputime);
                    randP = randperm(tmp_num);
                    tmp_num = 0;
                    for i=1:length(app.IWF.imgMat)
                        if strcmp(string(app.DEEP.deepLearningRes.type(i)),string(app.oriDeepFeat.type))
                            tmp_num = tmp_num + 1;
                            app.corrList(i) = randP(tmp_num);
                        else
                            app.corrList(i) = 99999;
                        end
                    end
                otherwise
                    app.corrList=app.CORR.corr_mat(app.oriImage.ID,:,str2num(app.MethodCho.Value),str2num(app.DisCho.Value));
            end
            if app.MethodCho.Value ~= '0'
                % 以下代码用于计算准确度
                nums=0;
                s_c=sort(app.corrList);
                for i=1:50
                    tmpI=find(s_c(i)==app.corrList);
                    if strcmp(app.IWF.imgMat(tmpI).type,app.oriImage.type)
                        nums=nums+1;
                    end
                end
                app.ExactLabel.Text=strcat(num2str(2*nums),"%");
            else % 显示深度学习的准确度
                app.ExactLabel.Text=strcat(num2str(100*app.DEEP.deepLearningRes.accuracy),"%");
            end
            % 找到最像的六张图片
            if app.SuperSuperWay.Value
                app.IDs=min6Super(app.corrList,app.oriImage.type,app.IWF.imgMat);
            else
                if ~app.DeepFeatCheck.Value
                    app.IDs=min6(app.corrList);
                else
                    app.IDs=min6Deep(app.corrList,app.oriImage.type,app.DEEP.deepLearningRes);
                end
            end
            updateTable(app);
        end 
        
        function updateTable(app)
            s_c=sort(app.corrList);
            corrComb=[
                app.CORR.corr_sum_nor(app.oriImage.ID,app.IDs(1))
                app.CORR.corr_sum_nor(app.oriImage.ID,app.IDs(2))
                app.CORR.corr_sum_nor(app.oriImage.ID,app.IDs(3))
                app.CORR.corr_sum_nor(app.oriImage.ID,app.IDs(4))
                app.CORR.corr_sum_nor(app.oriImage.ID,app.IDs(5))
                app.CORR.corr_sum_nor(app.oriImage.ID,app.IDs(6))];
            T=table((1:6)',app.IDs',s_c(2:7)',corrComb);
            app.DetailTable.Data=T;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IWFS, corrS, name)
            app.isRun=false;
            app.isCalced=false;
            app.ExactLabel.Text = "100%";
            app.DEEP=load('deep.mat');% 这个很小很小，每次重读无所谓
            app.TotAccRate.Text = strcat(app.TotAccRate.Text,num2str(100*app.DEEP.deepLearningRes.accuracy),"%");% 载入总精确度
            app.DeepSigAcc.Text = strcat(app.DeepSigAcc.Text,"100%");
            app.ImSimilar.Name = "图片相似度分析 -By Uiharu";
            app.ImSimilar.Resize = 0;% 不可调整大小
            if nargin~=1
                % 只要不为1，就不是全新启动App
                app.IWF=IWFS;
                app.CORR=corrS;
                if nargin==4
                    app.tmpVar=name;
                    searchImg(app);%更新预存信息
                    updateImgs(app);
                    app.isRun=true;
                    app.LoadPic.Text='更换图片';
                    app.CalcStrButten.Text='重新计算';
                end
            else
                app.IWF=load("imgWithFeatures.mat"); % 加载本地文件的图像信息
                app.CORR=load('corr.mat');% 加载相关度衡量矩阵
            end
        end

        % Button pushed function: LoadPic
        function LoadPicButtonPushed(app, event)
            % 显示文件选择对话框
            % debug时关闭文件加载
            if app.isRun
                Appic(app.IWF,app.CORR);
                delete(app.ImSimilar);
            else
                filterspec = {'*.jpg;*.tif;*.png;*.gif','选择原始图片'};
                [oriFile, ~] = uigetfile(filterspec);
                %             oriFile='tenniscourt03.tif';
                app.tmpVar=oriFile;
                searchImg(app);%更新预存信息
                updateImgs(app);
                % 加载深度信息
                app.TypeLabel.Text = app.oriDeepFeat.type;
                % 更新APP状态
                app.isRun = true;
                app.LoadPic.Text = '更换图片';
            end
        end

        % Value changed function: MethodCho
        function MethodChoValueChanged(app, event)
            %选择模式发生改变
            if app.MethodCho.Value=='9'||strcmp(app.MethodCho.Value,'0')
                app.DisCho.Value='2';
                app.DisCho.Enable=false;
            end
            if app.MethodCho.Value~='9'&&~strcmp(app.MethodCho.Value,'0')
                app.DisCho.Enable=true;
            end
            if app.MethodCho.Value=='1'||strcmp(app.MethodCho.Value,'0')
                % 禁止在灰度直方图特征和深度学习中使用类型特征
                app.SuperSuperWay.Value=false;
                app.SuperSuperWay.Enable=false;
            else
                app.SuperSuperWay.Enable=true;
            end
        end

        % Button pushed function: CalcStrButten
        function CalcStrButtenButtonPushed(app, event)
            if app.isCalced
                Appic(app.IWF,app.CORR,app.oriImage.name);
                delete(app.ImSimilar);
            else
                findPics(app);
                updateImgs(app);
                updateSim(app);
                app.isCalced=true;
                app.CalcStrButten.Text='更换方式';
            end
        end

        % Close request function: ImSimilar
        function ImSimilarCloseRequest(app, event)
            selection = uiconfirm(app.ImSimilar,'真的要走了喵？','正在离开应用',"Icon","close.png","Options",{'走啦','再玩会'});
            switch selection
            case '走啦'
                delete(app)
            case '再玩会'
                return
            end
            
        end

        % Menu selected function: StartMenu
        function StartMenuSelected(app, event)
            CalcStrButtenButtonPushed(app, event);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            LoadPicButtonPushed(app, event);
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            if app.ImgCho.Value=='0'
                drawHisImg=app.oriImage;
            else
                drawHisImg=app.IWF.imgMat(app.IDs(str2num(app.ImgCho.Value)));
            end
            switch app.HistCho.Value
                case '1'
                    stem(app.HIstAxes,drawHisImg.feats.Hist)
                case '2'
                    stem(app.HIstAxes,drawHisImg.feats.GLCM)
                case '3'
                    stem(app.HIstAxes,drawHisImg.feats.LBP)
            end
        end

        % Value changed function: SuperSuperWay
        function SuperSuperWayValueChanged(app, event)
            value = app.SuperSuperWay.Value;
            if value
                app.DeepFeatCheck.Value = false;
                app.DeepFeatCheck.Enable = false;
            else
                app.DeepFeatCheck.Value = true;
                app.DeepFeatCheck.Enable = true;
            end
        end

        % Value changed function: DeepFeatCheck
        function DeepFeatCheckValueChanged(app, event)
            value = app.DeepFeatCheck.Value;
            if value % 东方不亮西方亮
                app.SuperSuperWay.Value = false;
                app.SuperSuperWay.Enable = false;
            else
                app.SuperSuperWay.Enable = true;
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app.ImSimilar);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ImSimilar.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {506, 506, 506};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {506, 506};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 352};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImSimilar = uifigure('Visible', 'off');
            app.ImSimilar.Name = 'MATLAB App';
            app.ImSimilar.CloseRequestFcn = createCallbackFcn(app, @ImSimilarCloseRequest, true);
            app.ImSimilar.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ImSimilar);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadPic = uibutton(app.LeftPanel, 'push');
            app.LoadPic.ButtonPushedFcn = createCallbackFcn(app, @LoadPicButtonPushed, true);
            app.LoadPic.Text = '导入图像';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '相似度判据：';
            app.MethodCho = uidropdown(app.LeftPanel);
            app.MethodCho.Items = {'灰度直方图', '灰度共生矩阵特征', '灰度共生矩阵', '灰度差分统计', '灰度梯度共生矩阵', 'Laws纹理', 'LBP特征提取', 'Tamura特征', '所有特征归一', '深度学习'};
            app.MethodCho.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '0'};
            app.MethodCho.ValueChangedFcn = createCallbackFcn(app, @MethodChoValueChanged, true);
            app.MethodCho.Value = '1';
            app.CalcStrButten = uibutton(app.LeftPanel, 'push');
            app.CalcStrButten.ButtonPushedFcn = createCallbackFcn(app, @CalcStrButtenButtonPushed, true);
            app.CalcStrButten.Text = '开始计算';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '距离模式：';
            app.DisCho = uidropdown(app.LeftPanel);
            app.DisCho.Items = {'欧式距离', '向量夹角余弦', '相关系数'};
            app.DisCho.ItemsData = {'1', '2', '3'};
            app.DisCho.Value = '1';
            app.DepartLine_3 = uilabel(app.LeftPanel);
            app.DepartLine_3.Text = '—————————————————';
            app.MethodLabel = uilabel(app.LeftPanel);
            app.MethodLabel.Text = '使用特征计算';
            app.DepartLine_4 = uilabel(app.LeftPanel);
            app.DepartLine_4.Text = '—————————————————';
            app.OriginalImage = uiimage(app.LeftPanel);
            app.OriginalImage.ImageSource = 'examPic.png';
            app.SuperSuperWay = uicheckbox(app.LeftPanel);
            app.SuperSuperWay.ValueChangedFcn = createCallbackFcn(app, @SuperSuperWayValueChanged, true);
            app.SuperSuperWay.Enable = 'off';
            app.SuperSuperWay.Text = '同类比较';
            app.TotAccRate = uilabel(app.LeftPanel);
            app.TotAccRate.Text = '总精确度：';
            app.TypeLabel = uilabel(app.LeftPanel);
            app.TypeLabel.Text = '图像类别(深度学习结果)';
            app.DeepSigAcc = uilabel(app.LeftPanel);
            app.DeepSigAcc.Text = '识别精确度：';
            app.DeepLabel = uilabel(app.LeftPanel);
            app.DeepLabel.Text = '深度学习精确度';
            app.DeepFeatCheck = uicheckbox(app.LeftPanel);
            app.DeepFeatCheck.ValueChangedFcn = createCallbackFcn(app, @DeepFeatCheckValueChanged, true);
            app.DeepFeatCheck.Text = '深度特征辅助';
            app.DeepFeatCheck.Value = true;
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image2 = uiimage(app.CenterPanel);
            app.Image3 = uiimage(app.CenterPanel);
            app.Image4 = uiimage(app.CenterPanel);
            app.Image5 = uiimage(app.CenterPanel);
            app.Image0 = uiimage(app.CenterPanel);
            app.Image1 = uiimage(app.CenterPanel);
            app.Label_5 = uilabel(app.CenterPanel);
            app.Label_5.Text = '图1';
            app.Label_6 = uilabel(app.CenterPanel);
            app.Label_6.Text = '图2';
            app.Label_7 = uilabel(app.CenterPanel);
            app.Label_7.Text = '图3';
            app.Label_8 = uilabel(app.CenterPanel);
            app.Label_8.Text = '图4';
            app.Label_9 = uilabel(app.CenterPanel);
            app.Label_9.Text = '图5';
            app.Label_10 = uilabel(app.CenterPanel);
            app.Label_10.Text = '图6';
            app.ExactLabel = uilabel(app.CenterPanel);
            app.ExactLabel.Text = '100%';
            app.AccNameLabel = uilabel(app.CenterPanel);
            app.AccNameLabel.Text = {'精确度'; '(前50位的同类率)'};
            app.RightPanel = uipanel(app.GridLayout);
            app.DepartLine_2 = uilabel(app.RightPanel);
            app.DepartLine_2.Text = '——————————————————————————';
            app.DetailTable = uitable(app.RightPanel);
            app.DetailTable.ColumnName = {'次序'; 'ID'; '当前模式与原图距离'; '全特征值综合距离'};
            app.DetailTable.RowName = {};
            app.DetailTable.ColumnSortable = true;
            app.Label_11 = uilabel(app.RightPanel);
            app.Label_11.Text = '相关距离（越小越相似）';
            app.Label_12 = uilabel(app.RightPanel);
            app.Label_12.Text = '选择图像';
            app.ImgCho = uidropdown(app.RightPanel);
            app.ImgCho.Items = {'原图', '图一', '图二', '图三', '图四', '图五', '图六'};
            app.ImgCho.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ImgCho.Value = '0';
            app.Label_13 = uilabel(app.RightPanel);
            app.Label_13.Text = '选择直方图';
            app.HistCho = uidropdown(app.RightPanel);
            app.HistCho.Items = {'灰度直方图', '灰度共生矩阵', 'LBP直方图'};
            app.HistCho.ItemsData = {'1', '2', '3'};
            app.HistCho.Value = '1';
            app.ShowButton = uibutton(app.RightPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.HIstAxes = uiaxes(app.RightPanel);
            title(app.HIstAxes, '直方图显示')
            xlabel(app.HIstAxes, '灰度或其他量化特征')
            ylabel(app.HIstAxes, '频率')
            zlabel(app.HIstAxes, 'Z')
            app.StartMenu = uimenu(app.ImSimilar);
            app.StartMenu.MenuSelectedFcn = createCallbackFcn(app, @StartMenuSelected, true);
            app.StartMenu.Text = '开始计算';
            app.LoadMenu = uimenu(app.ImSimilar);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = '导入图片';
            app.ExitMenu = uimenu(app.ImSimilar);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = '退出';
            % Show the figure after all components are created
            app.ImSimilar.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImSimilar)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImSimilar)
        end
    end
end